classdef AnalysisApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainFigure                    matlab.ui.Figure
        segmentsSourceButtonGroup     matlab.ui.container.ButtonGroup
        manualSegmentationRadio       matlab.ui.control.RadioButton
        automaticSegmentationRadio    matlab.ui.control.RadioButton
        createButton                  matlab.ui.control.Button
        segmentationPanel             matlab.ui.container.Panel
        segmentationVariablesTable    matlab.ui.control.Table
        segmentationList              matlab.ui.control.ListBox
        segmentationLabel             matlab.ui.control.Label
        groupingPanel                 matlab.ui.container.Panel
        groupingStrategiesList        matlab.ui.control.ListBox
        groupsLabel                   matlab.ui.control.Label
        plotPanel                     matlab.ui.container.Panel
        visualizationPanel            matlab.ui.container.Panel
        signalComputerVariablesTableVisualization  matlab.ui.control.Table
        signalComputersListVisualization  matlab.ui.control.ListBox
        signalsListVisualization      matlab.ui.control.ListBox
        visualizationClassesList      matlab.ui.control.ListBox
        PlotStyleButtonGroup          matlab.ui.container.ButtonGroup
        overlappingPlotRadio          matlab.ui.control.RadioButton
        sequentialPlotRadio           matlab.ui.control.RadioButton
        visualizeButton               matlab.ui.control.Button
        plotToolbar                   matlab.ui.container.ButtonGroup
        zoomButton                    matlab.ui.control.ToggleButton
        panButton                     matlab.ui.control.ToggleButton
        preprocessingPanel            matlab.ui.container.Panel
        preprocessingSignalComputerVariablesTable  matlab.ui.control.Table
        preprocessingSignalComputerList  matlab.ui.control.ListBox
        preprocessingSignalsList      matlab.ui.control.ListBox
        sameScaleCheckbox             matlab.ui.control.CheckBox
        filesPanel                    matlab.ui.container.Panel
        inputFilesList                matlab.ui.control.ListBox
        selectAllInputFilesList       matlab.ui.control.CheckBox
        annotationsPanel              matlab.ui.container.ButtonGroup
        includeEventsCheckBox         matlab.ui.control.CheckBox
        includeRangesCheckBox         matlab.ui.control.CheckBox
        resetZoomButton               matlab.ui.control.Button
        clearCacheButton              matlab.ui.control.Button
        labelingPanel                 matlab.ui.container.Panel
        labelGroupingsList            matlab.ui.control.ListBox
        labelingVariablesTable        matlab.ui.control.Table
        Statistics                    matlab.ui.container.Panel
        statisticsTable               matlab.ui.control.Table
        eventDetectionPanel           matlab.ui.container.Panel
        eventDetectionPreprocessingSignalComputerVariablesTable  matlab.ui.control.Table
        eventDetectionPreprocessingSignalComputerList  matlab.ui.control.ListBox
        eventDetectionPreprocessingSignalsList  matlab.ui.control.ListBox
        eventDetectionVariablesTable  matlab.ui.control.Table
        eventDetectionList            matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        % chain
        arcChain;
        
        %classes
        classesMap;
        annotations;
        
        %data
        dataLoader;
        manualSegmentation;
        fileLoader;
        
        %data
        segments;
        groupedSegments;
        filteredSegments;
        
        %preprocessing
        preprocessingConfigurator;
        eventDetectorConfigurator;
        segmentationConfigurator;
        labelingConfigurator;
        
        groupingConfigurator;
        preprocessingConfiguratorVisualization;
        isManualMode;
        
        %ui plotting
        segmentsPlotter;
        uiHandles;
    end
    
    methods (Access = private)
        
        %% UI
        function showLines = getShowLinesCheckbox(app)
            showLines = app.showLinesCheckbox.Value;
        end
        
        function includeEvents = getIncludeEvents(app)
            includeEvents = app.includeEventsCheckBox.Value;
        end
        
        function includeRanges = getIncludeRanges(app)
            includeRanges = app.includeRangesCheckBox.Value;
        end
        
        function resetGroupsLabel(app)
            app.groupsLabel.Text = "";
        end
        
        function resetSegmentsLabel(app)
            app.segmentationLabel.Text = "";
        end
        
        function updateGroupsLabel(app)
            str = sprintf('%d groups.',length(app.groupedSegments));
            app.groupsLabel.Text = str;
        end
        
        function updateSegmentsLabel(app)
            str = sprintf('%d files.',length(app.segments));
            app.segmentationLabel.Text = str;
        end
        
        function value = getSameScale(app)
            value = app.sameScaleCheckbox.Value;
        end
        
        function updateGroupsList(app)
            labelGrouping = app.groupingConfigurator.getCurrentLabelGrouping();
            nClasses = length(app.groupedSegments);
            classesStr = cell(1,nClasses);
            
            for i = 1 : labelGrouping.numClasses
                className = labelGrouping.classNames{i};
                nSegments = length(app.groupedSegments{i});
                str = sprintf('%s: %d',className,nSegments);
                classesStr{i} = str;
            end
            
            nSegments = length(app.groupedSegments{nClasses});
            classesStr{nClasses} = sprintf('%s: %d',Constants.kNullClassGroupStr,nSegments);
            app.visualizationClassesList.Items = classesStr;
            app.visualizationClassesList.Value = app.visualizationClassesList.Items;
        end
        
        function updateEventDetectionTablesVisibility(app)
            if app.isManualMode
                app.preprocessingPanel.Visible = 'Off';
                app.eventDetectionPanel.Visible = 'Off';
                app.annotationsPanel.Visible = 'On';
                
            else
                app.preprocessingPanel.Visible = 'On';
                app.eventDetectionPanel.Visible = 'On';
                app.annotationsPanel.Visible = 'Off';
            end
        end
    
        function updateLabelingPanelVisibility(app)
            if app.isManualMode
                app.labelingPanel.Visible = 'Off';
            else
                app.labelingPanel.Visible = 'On';
            end
        end
    
        function idxs = getSelectedClassIdxs(app)
            idxStr = app.visualizationClassesList.Value;
            [~,idxs] = ismember(idxStr,app.visualizationClassesList.Items);
            idxs = sort(idxs);
        end
        
        
        %% methods
        function loadUIConfigurators(app)
            
            signalNames = Helper.LoadSignals();
            
            preprocessingComputers = Palette.PreprocessingComputers();
            
            app.preprocessingConfigurator = PreprocessingConfigurator(...
            signalNames,...    
            preprocessingComputers,...
                app.preprocessingSignalsList,...
                app.preprocessingSignalComputerList,...
                app.preprocessingSignalComputerVariablesTable);
            
            app.preprocessingConfiguratorVisualization = PreprocessingConfigurator(...
                signalNames,...
                preprocessingComputers,...
                app.signalsListVisualization,...
                app.signalComputersListVisualization,...
                app.signalComputerVariablesTableVisualization);
            
            app.signalsListVisualization.Value = app.signalsListVisualization.Items{1};
            
            eventDetectionUIElements = EventDetectionConfiguratorUIElements();
            eventDetectionUIElements.preprocessingSignalsList = app.eventDetectionPreprocessingSignalsList;
            eventDetectionUIElements.preprocessingSignalComputerList = app.eventDetectionPreprocessingSignalComputerList;
            eventDetectionUIElements.preprocessingSignalComputerVariablesTable = app.eventDetectionPreprocessingSignalComputerVariablesTable;
            eventDetectionUIElements.eventDetectionList = app.eventDetectionList;
            eventDetectionUIElements.eventDetectionVariablesTable = app.eventDetectionVariablesTable;
            
            eventDetectors = Palette.EventDetectionComputers();
            
            app.eventDetectorConfigurator = EventDetectionConfigurator(...
                signalNames, preprocessingComputers, eventDetectors, eventDetectionUIElements);
            
            
            segmentationStrategies = {app.manualSegmentation};
            
            app.segmentationConfigurator = ComputerConfigurator(...
                segmentationStrategies,...
                app.segmentationList...
                ,app.segmentationVariablesTable);
            
            app.labelingConfigurator = ComputerConfigurator(...
                Palette.LabelingStrategyComputers(),...
                app.labelGroupingsList,...
                app.labelingVariablesTable);
            
            labelGroupings = app.dataLoader.loadAllLabelGroupings();
            
            app.groupingConfigurator = GroupingConfigurator(...
                labelGroupings,app.groupingStrategiesList);
        end
        
        function plotData(app)
            
            labelGrouping = app.groupingConfigurator.getCurrentLabelGrouping();
            classNames = labelGrouping.classNames;
            nClasses = length(labelGrouping.classNames);
            classNames{nClasses+1} = Constants.kNullClassGroupStr;
            
            selectedClassesIdxs = app.getSelectedClassIdxs();
            groupNames = classNames(selectedClassesIdxs);
            
            app.segmentsPlotter.sameScale = app.getSameScale();
            
            app.segmentsPlotter.sequentialSegments = app.sequentialPlotRadio.Value;
            
            app.segmentsPlotter.plotSegments(app.filteredSegments,groupNames);
        end
        
        function applySignalComputers(app)
            
            filterComputer = app.preprocessingConfiguratorVisualization.createPreprocessingComputerWithUIParameters();
            if isempty(filterComputer)
                filterComputer = app.preprocessingConfiguratorVisualization.createAxisSelectorWithUIParameters();
            end
            
            selectedClassesIdxs = app.getSelectedClassIdxs();
            
            nSelectedClasses = length(selectedClassesIdxs);
            
            app.filteredSegments = cell(1,nSelectedClasses);
            
            for i = 1 : nSelectedClasses
                currentClassIdx = selectedClassesIdxs(i);
                segmentsCurrentGroup = app.groupedSegments{currentClassIdx};
                segmentsArray = repmat(Segment(),1,length(segmentsCurrentGroup));
                
                for j = 1 : length(segmentsArray)
                    segment = segmentsCurrentGroup(j);
                    filteredData = filterComputer.compute(segment.data);
                    filteredSegment = Segment(segment.file,filteredData,segment.label,segment.eventIdx);
                    segmentsArray(j) = filteredSegment;
                end
                app.filteredSegments{i} = segmentsArray;
            end
        end
        
        function buildChainFromUI(app)
            
            segmentationAlgorithm = app.segmentationConfigurator.createComputerWithUIParameters();
            
            app.fileLoader = FileLoader();
            
            labelGrouping = app.groupingConfigurator.getCurrentLabelGrouping();
            segmentsMapper = SegmentsClassToLabelMapper(labelGrouping);
            
            if app.isManualMode
                segmentationAlgorithm.includeEvents = app.getIncludeEvents();
                segmentationAlgorithm.includeRanges = app.getIncludeRanges();
                
                app.arcChain = Computer.ComputerWithSequence({app.fileLoader,segmentationAlgorithm,segmentsMapper});
            else
                preprocessor = app.preprocessingConfigurator.createPreprocessingComputerWithUIParameters();
                eventDetector = app.eventDetectorConfigurator.createEventDetectorWithUIParameters();
                labeler = app.labelingConfigurator.createComputerWithUIParameters();
                propertyGetter = PropertyGetter('data');
                
                computerSequence = {app.fileLoader,propertyGetter};
                
                if ~isempty(preprocessor)
                    computerSequence{end+1} = preprocessor;
                end
                
                if ~isempty(eventDetector)
                    computerSequence{end+1} = eventDetector;
                end
                
                computerSequence{end+1} = segmentationAlgorithm;
                computerSequence{end+1} = labeler;
                computerSequence{end+1} = segmentsMapper;
                
                app.arcChain = Computer.ComputerWithSequence(computerSequence);
            end
        end
        
        function validData = checkValidComputedSegments(app)
            validData = true;
            for i = 1 : length(app.filteredSegments)
                segmentsCurrentPlayer = app.filteredSegments{i};
                for j = 1 : length(segmentsCurrentPlayer)
                    segment = segmentsCurrentPlayer(j);
                    if isempty(segment) || isempty(segment.data)
                        validData = false;
                        break;
                    end
                end
                if validData == false
                    break;
                end
            end
        end
        
        function executeChain(app)
            
            [~,selectedIdxs] = ismember(app.inputFilesList.Value,app.inputFilesList.Items);
            selectedIdxs = sort(selectedIdxs);
            
            nFiles = length(selectedIdxs);
            app.segments = cell(1,nFiles);
            try
                for i = 1 : nFiles
                    selectedIdx = selectedIdxs(i);
                    app.fileLoader.fileName = app.inputFilesList.Items{selectedIdx};
                    
                    annotation = app.annotations(selectedIdx);
                    Computer.SetSharedContextVariable(Constants.kSharedVariableCurrentAnnotationFile, annotation);
                    
                    app.segments{i} = Computer.ExecuteChain(app.arcChain,'start',true);
                end
            catch exception
                
                warning(Constants.kAlgorithmExecutionFailedWarning);
                msgText = getReport(exception);
                warning(msgText);
            end
        end
        
        function populateFilesList(app)
            
            fileNames = Helper.listDataFiles();
            app.inputFilesList.Items = fileNames(1:end);
            app.inputFilesList.Value = app.inputFilesList.Items;
        end
        
        function meanRangeDurations = calculateMeanRangeDurations(app)
            nGroups = length(app.groupedSegments);
            meanRangeDurations = zeros(1,nGroups-1);
            for i = 1 : nGroups-1
                segmentsCurrentClass = app.groupedSegments{i};
                if ~isempty(segmentsCurrentClass)
                    meanRangeDurations(i) = mean([segmentsCurrentClass.endSample] - [segmentsCurrentClass.startSample] + 1);
                end
            end
        end
        
        function updateStatisticsTable(app,labelGrouping)
            
            nClasses = length(app.groupedSegments);
            data = cell(nClasses+1,2);
            meanSegmentDurations = app.calculateMeanRangeDurations();
            
            sumRelevantInstances = 0;
            for i = 1 : labelGrouping.numClasses
                classLabel = labelGrouping.classNames{i};
                data{i,1} = classLabel;
                data{i,2} = length(app.groupedSegments{i});
                data{i,3} = sprintf('%.1f',meanSegmentDurations(i));
                sumRelevantInstances = sumRelevantInstances + length(app.groupedSegments{i});
            end
            
            data{nClasses,1} = Constants.kNullClassGroupStr;
            data{nClasses,2} = length(app.groupedSegments{nClasses});
            data{nClasses,3} = '-';
            
            data{nClasses+1,1} = 'Total Relevant';
            data{nClasses+1,2} = sumRelevantInstances;
            data{nClasses+1,3} = sprintf('%.1f',mean(meanSegmentDurations));
            
            app.statisticsTable.Data = data;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.mainFigure.Visible = false;
            close all;
            clear ClassesMap;
            clear Cache;
            movegui(app.mainFigure,'center');
            app.mainFigure.Visible = true;
            
            app.classesMap = ClassesMap.instance();
            app.dataLoader = DataLoader();
            
            app.annotations = app.dataLoader.loadAllAnnotations();
            app.manualSegmentation = ManualSegmentation(app.annotations);
            app.isManualMode = true;
            
            app.loadUIConfigurators();
            
            app.populateFilesList();
            
            app.segmentsPlotter = AnalysisSegmentsPlotter(app.plotPanel);
        end

        % Button pushed function: createButton
        function executeButtonPushed(app, event)
            if(~app.isManualMode || app.getIncludeEvents() || app.getIncludeRanges())
                app.resetGroupsLabel();
                app.groupedSegments = [];
                
                app.buildChainFromUI();
                
                app.executeChain();
                              
                app.updateSegmentsLabel();
                
                segmentsGrouper = SegmentsGrouper();
                labelGrouping = app.groupingConfigurator.getCurrentLabelGrouping();
                app.groupedSegments = segmentsGrouper.groupSegments(app.segments,labelGrouping.numClasses);
                app.updateGroupsList();
                app.updateGroupsLabel();
                app.updateStatisticsTable(labelGrouping);
            end
        end

        % Button pushed function: visualizeButton
        function visualizeButtonPushed(app, event)

            if ~isempty(app.groupedSegments)
                app.applySignalComputers();
                validData = app.checkValidComputedSegments();
                if validData
                    app.plotData();
                else
                    fprintf('SignalExplorerApp - %s\n',Constants.kInvalidFilterComputedError);
                end
            end
        end

        % Selection changed function: plotToolbar
        function handleToolbarButtonChanged(app, event)
            selectedButton = app.plotToolbar.SelectedObject;
            
            str = Helper.getOnOffStringLowerCase(true);
            if(selectedButton == app.zoomButton)
                app.segmentsPlotter.setZoom(str);
            else
                app.segmentsPlotter.setPan(str);
            end
        end

        % Selection changed function: segmentsSourceButtonGroup
        function segmentsSourceButtonGroupSelectionChanged(app, event)
            selectedButton = app.segmentsSourceButtonGroup.SelectedObject;
            if(selectedButton == app.manualSegmentationRadio)
                
                app.isManualMode = true;
                app.updateEventDetectionTablesVisibility();
                app.updateLabelingPanelVisibility();
                app.segmentationConfigurator.computers = {app.manualSegmentation};
                app.segmentationConfigurator.reloadUI();
            else
                app.isManualMode = false;
                app.updateEventDetectionTablesVisibility();
                app.updateLabelingPanelVisibility();
                
                eventSegmentation = EventSegmentation();
                slidingWindowSegmentation = SlidingWindowSegmentation();
                app.segmentationConfigurator.computers = {eventSegmentation,slidingWindowSegmentation};
                app.segmentationConfigurator.reloadUI();
            end
        end

        % Value changed function: sameScaleCheckbox
        function sameScaleCheckboxValueChanged(app, event)
            sameScale = app.sameScaleCheckbox.Value;
            if(~isempty(app.segmentsPlotter))
                app.segmentsPlotter.setSameScale(sameScale);
            end
        end

        % Value changed function: selectAllInputFilesList
        function selectAllInputFilesListValueChanged(app, event)
            value = app.selectAllInputFilesList.Value;
            if(value)
                app.inputFilesList.Value = app.inputFilesList.Items;
            end
        end

        % Button pushed function: resetZoomButton
        function resetZoomButtonPushed(app, event)
            app.segmentsPlotter.resetZoom();
        end

        % Button pushed function: clearCacheButton
        function clearCacheButtonPushed(app, event)
            Cache.ClearAllCacheFiles();
            clear Cache;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainFigure = uifigure('Visible', 'off');
            app.mainFigure.Name = 'Analysis App';
            app.segmentsSourceButtonGroup = uibuttongroup(app.mainFigure);
            app.segmentsSourceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @segmentsSourceButtonGroupSelectionChanged, true);
            app.segmentsSourceButtonGroup.Title = 'Data Source';
            app.manualSegmentationRadio = uiradiobutton(app.segmentsSourceButtonGroup);
            app.manualSegmentationRadio.Text = 'Manual annotations';
            app.manualSegmentationRadio.Value = true;
            app.automaticSegmentationRadio = uiradiobutton(app.segmentsSourceButtonGroup);
            app.automaticSegmentationRadio.Text = 'Automatic segmentation';
            app.createButton = uibutton(app.mainFigure, 'push');
            app.createButton.ButtonPushedFcn = createCallbackFcn(app, @executeButtonPushed, true);
            app.createButton.Text = 'Execute';
            app.segmentationPanel = uipanel(app.mainFigure);
            app.segmentationPanel.Title = 'Segmentation';
            app.segmentationVariablesTable = uitable(app.segmentationPanel);
            app.segmentationVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.segmentationVariablesTable.RowName = {};
            app.segmentationVariablesTable.ColumnEditable = [false true];
            app.segmentationList = uilistbox(app.segmentationPanel);
            app.segmentationList.Items = {};
            app.segmentationList.Value = {};
            app.segmentationLabel = uilabel(app.segmentationPanel);
            app.segmentationLabel.Text = '';
            app.groupingPanel = uipanel(app.mainFigure);
            app.groupingPanel.Title = 'Class Grouping';
            app.groupingStrategiesList = uilistbox(app.groupingPanel);
            app.groupingStrategiesList.Items = {};
            app.groupingStrategiesList.Value = {};
            app.groupsLabel = uilabel(app.groupingPanel);
            app.groupsLabel.Text = '';
            app.plotPanel = uipanel(app.mainFigure);
            app.visualizationPanel = uipanel(app.mainFigure);
            app.visualizationPanel.Title = 'Visualization';
            app.signalComputerVariablesTableVisualization = uitable(app.visualizationPanel);
            app.signalComputerVariablesTableVisualization.ColumnName = {'Variable'; 'Value'};
            app.signalComputerVariablesTableVisualization.RowName = {};
            app.signalComputerVariablesTableVisualization.ColumnEditable = [false true];
            app.signalComputersListVisualization = uilistbox(app.visualizationPanel);
            app.signalComputersListVisualization.Items = {};
            app.signalComputersListVisualization.Value = {};
            app.signalsListVisualization = uilistbox(app.visualizationPanel);
            app.signalsListVisualization.Items = {};
            app.signalsListVisualization.Multiselect = 'on';
            app.signalsListVisualization.Value = {};
            app.visualizationClassesList = uilistbox(app.visualizationPanel);
            app.visualizationClassesList.Items = {};
            app.visualizationClassesList.Multiselect = 'on';
            app.visualizationClassesList.Value = {};
            app.PlotStyleButtonGroup = uibuttongroup(app.visualizationPanel);
            app.PlotStyleButtonGroup.Title = 'Plot Style';
            app.overlappingPlotRadio = uiradiobutton(app.PlotStyleButtonGroup);
            app.overlappingPlotRadio.Text = 'Overlapping';
            app.overlappingPlotRadio.Value = true;
            app.sequentialPlotRadio = uiradiobutton(app.PlotStyleButtonGroup);
            app.sequentialPlotRadio.Text = 'Sequential';
            app.visualizeButton = uibutton(app.visualizationPanel, 'push');
            app.visualizeButton.ButtonPushedFcn = createCallbackFcn(app, @visualizeButtonPushed, true);
            app.visualizeButton.Text = 'Visualize';
            app.plotToolbar = uibuttongroup(app.mainFigure);
            app.plotToolbar.SelectionChangedFcn = createCallbackFcn(app, @handleToolbarButtonChanged, true);
            app.zoomButton = uitogglebutton(app.plotToolbar);
            app.zoomButton.Text = 'Zoom';
            app.panButton = uitogglebutton(app.plotToolbar);
            app.panButton.Text = 'Pan';
            app.panButton.Value = true;
            app.preprocessingPanel = uipanel(app.mainFigure);
            app.preprocessingPanel.Title = 'Preprocessing';
            app.preprocessingPanel.Visible = 'off';
            app.preprocessingSignalComputerVariablesTable = uitable(app.preprocessingPanel);
            app.preprocessingSignalComputerVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.preprocessingSignalComputerVariablesTable.RowName = {};
            app.preprocessingSignalComputerVariablesTable.ColumnEditable = [false true];
            app.preprocessingSignalComputerList = uilistbox(app.preprocessingPanel);
            app.preprocessingSignalComputerList.Items = {};
            app.preprocessingSignalComputerList.Value = {};
            app.preprocessingSignalsList = uilistbox(app.preprocessingPanel);
            app.preprocessingSignalsList.Items = {};
            app.preprocessingSignalsList.Multiselect = 'on';
            app.preprocessingSignalsList.Value = {};
            app.sameScaleCheckbox = uicheckbox(app.mainFigure);
            app.sameScaleCheckbox.ValueChangedFcn = createCallbackFcn(app, @sameScaleCheckboxValueChanged, true);
            app.sameScaleCheckbox.Text = 'Same scale';
            app.sameScaleCheckbox.Value = true;
            app.filesPanel = uipanel(app.mainFigure);
            app.filesPanel.Title = 'Input Files';
            app.inputFilesList = uilistbox(app.filesPanel);
            app.inputFilesList.Items = {};
            app.inputFilesList.Multiselect = 'on';
            app.inputFilesList.Value = {};
            app.selectAllInputFilesList = uicheckbox(app.filesPanel);
            app.selectAllInputFilesList.ValueChangedFcn = createCallbackFcn(app, @selectAllInputFilesListValueChanged, true);
            app.selectAllInputFilesList.Text = 'select all';
            app.annotationsPanel = uibuttongroup(app.mainFigure);
            app.annotationsPanel.Title = 'Annotations';
            app.includeEventsCheckBox = uicheckbox(app.annotationsPanel);
            app.includeEventsCheckBox.Text = 'Events';
            app.includeEventsCheckBox.Value = true;
            app.includeRangesCheckBox = uicheckbox(app.annotationsPanel);
            app.includeRangesCheckBox.Text = 'Ranges';
            app.resetZoomButton = uibutton(app.mainFigure, 'push');
            app.resetZoomButton.ButtonPushedFcn = createCallbackFcn(app, @resetZoomButtonPushed, true);
            app.resetZoomButton.Text = 'Reset zoom';
            app.clearCacheButton = uibutton(app.mainFigure, 'push');
            app.clearCacheButton.ButtonPushedFcn = createCallbackFcn(app, @clearCacheButtonPushed, true);
            app.clearCacheButton.Text = 'Clear Cache';
            app.labelingPanel = uipanel(app.mainFigure);
            app.labelingPanel.Title = 'Labeling';
            app.labelingPanel.Visible = 'off';
            app.labelGroupingsList = uilistbox(app.labelingPanel);
            app.labelGroupingsList.Items = {};
            app.labelGroupingsList.Value = {};
            app.labelingVariablesTable = uitable(app.labelingPanel);
            app.labelingVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.labelingVariablesTable.RowName = {};
            app.labelingVariablesTable.ColumnEditable = [false true];
            app.Statistics = uipanel(app.mainFigure);
            app.Statistics.Title = 'Statistics';
            app.statisticsTable = uitable(app.Statistics);
            app.statisticsTable.ColumnName = {'Class'; '#'; 'Avg Size'};
            app.statisticsTable.RowName = {};
            app.statisticsTable.ColumnEditable = false;
            app.eventDetectionPanel = uipanel(app.mainFigure);
            app.eventDetectionPanel.Title = '3-Event Detection (optional)';
            app.eventDetectionPanel.Visible = 'off';
            app.eventDetectionPreprocessingSignalComputerVariablesTable = uitable(app.eventDetectionPanel);
            app.eventDetectionPreprocessingSignalComputerVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.eventDetectionPreprocessingSignalComputerVariablesTable.RowName = {};
            app.eventDetectionPreprocessingSignalComputerVariablesTable.ColumnEditable = [false true];
            app.eventDetectionPreprocessingSignalComputerList = uilistbox(app.eventDetectionPanel);
            app.eventDetectionPreprocessingSignalComputerList.Items = {};
            app.eventDetectionPreprocessingSignalComputerList.Value = {};
            app.eventDetectionPreprocessingSignalsList = uilistbox(app.eventDetectionPanel);
            app.eventDetectionPreprocessingSignalsList.Items = {};
            app.eventDetectionPreprocessingSignalsList.Multiselect = 'on';
            app.eventDetectionPreprocessingSignalsList.Value = {};
            app.eventDetectionVariablesTable = uitable(app.eventDetectionPanel);
            app.eventDetectionVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.eventDetectionVariablesTable.RowName = {};
            app.eventDetectionVariablesTable.ColumnEditable = [false true];
            app.eventDetectionList = uilistbox(app.eventDetectionPanel);
            app.eventDetectionList.Items = {};
            app.eventDetectionList.Value = {};
            % Show the figure after all components are created
            app.mainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnalysisApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainFigure)
        end
    end
end