classdef EventDetectionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        plotAxes                     matlab.ui.control.UIAxes
        preprocessingPanel           matlab.ui.container.Panel
        preprocessingSignalComputerVariablesTable  matlab.ui.control.Table
        preprocessingSignalComputerList  matlab.ui.control.ListBox
        preprocessingSignalsList     matlab.ui.control.ListBox
        eventDetectionPanel          matlab.ui.container.Panel
        eventDetectorVariablesTable  matlab.ui.control.Table
        eventDetectorList            matlab.ui.control.ListBox
        groupingPanel                matlab.ui.container.Panel
        labelingStrategiesList       matlab.ui.control.ListBox
        groupingTable                matlab.ui.control.Table
        visualizationPanel           matlab.ui.container.Panel
        visualizationFilesList       matlab.ui.control.ListBox
        visualizeButton              matlab.ui.control.Button
        plotToolbar                  matlab.ui.container.ButtonGroup
        zoomButton                   matlab.ui.control.ToggleButton
        panButton                    matlab.ui.control.ToggleButton
        exportPanel                  matlab.ui.container.Panel
        saveEventsButton             matlab.ui.control.Button
        resultsPanel                 matlab.ui.container.Panel
        ToleranceEditFieldLabel      matlab.ui.control.Label
        toleranceTextBox             matlab.ui.control.NumericEditField
        computeButton                matlab.ui.control.Button
        showDetectedEventsCheckBox   matlab.ui.control.CheckBox
        showMissedEventsCheckBox     matlab.ui.control.CheckBox
        showFalsePositiveEventsCheckBox  matlab.ui.control.CheckBox
        dataPanel                    matlab.ui.container.Panel
        filesList                    matlab.ui.control.ListBox
        selectAllFilesCheckBox       matlab.ui.control.CheckBox
        filesResultTable             matlab.ui.control.Table
        classesResultTable           matlab.ui.control.Table
    end

    
    properties (Access = private)
        
        %data
        dataLoader;
        eventsLoader;
        fileEnergies;
        fileNames;
        annotationsPerFile;
        
        %preprocessing
        preprocessedSignalsLoader;
        
        %detection
        eventDetectorConfigurator;
        eventsPerFile;
        
        %labeling strategies
        labelingConfigurator;
        currentLabelingStrategy;
        
        %results
        resultsComputer;
        resultsPerFile;
        
        %ui plotting
        scrollPanel;
        eventsPlotter;
        preprocessingConfigurator;
        figureHandle;
        uiHandles;
        axesHandles;
    end
    
    properties (Access = public, Constant)
        kMaxFileNameLength = 12;
    end
    
    methods (Access = private)
        
        function idxs = getSelectedFileIdxs(app)
            selectedIdxsStr = app.filesList.Value;
            [~,idxs] = ismember(selectedIdxsStr,app.filesList.Items);
            idxs = sort(idxs);
        end
        
        function loadPreprocessedData(app,fileIdxs)
            signalComputer = app.preprocessingConfigurator.createSignalComputerWithUIParameters();
            app.preprocessedSignalsLoader.preprocessor = signalComputer;
            app.fileEnergies = app.preprocessedSignalsLoader.loadOrCreateData();
            app.fileEnergies = app.fileEnergies(fileIdxs);
        end
    
        function tolerance = getTolerance(app)
            tolerance = app.toleranceTextBox.Value;
        end
        
        function showResultTables(app)
            app.filesResultTable.Visible = true;
            app.classesResultTable.Visible = true;
        end
    
        function hideResultTables(app)
            app.filesResultTable.Visible = false;
            app.classesResultTable.Visible = false;
        end
        
        function updateFilesTable(app,selectedFileIdxs)
            
            nRows = length(app.resultsPerFile);
            resultsTable = cell(nRows+1,3);
            
            for i = 1 : nRows
                fileIdx = selectedFileIdxs(i);
                fileName = app.fileNames{fileIdx};
                fileResults = app.resultsPerFile(i);
                
                resultsTable{i,1} = fileName;
                resultsTable{i,2} = sprintf('%.2f%%',100 * fileResults.goodEventRate);
                resultsTable{i,3} = sprintf('x%.2f',fileResults.badEventRate);
            end
            
            aggregatedFilesResults = DetectionAggregatedResults.DetectionAggregatedResultsWithDetectionResults(app.resultsPerFile);
            resultsTable{nRows+1,1} = 'Total';
            resultsTable{nRows+1,2} = sprintf('%.2f%%',100 * aggregatedFilesResults.nGoodEventsDetected / aggregatedFilesResults.nGoodEvents);
            resultsTable{nRows+1,3} = sprintf('x%.2f%',aggregatedFilesResults.nBadEventsDetected / aggregatedFilesResults.nGoodEvents);
            app.filesResultTable.Data = cell2table(resultsTable);
        end
            
        function updateClassResultTable(app,classDetectionStatistics)
            
            nGoodEventsDetectedPerClass = classDetectionStatistics.nGoodEventsDetectedPerClass;
            nTotalGoodEventsPerClass = classDetectionStatistics.nGoodEventsPerClass;
            
            %nRows = length(selectedLabels);
            
            nRows = length(nGoodEventsDetectedPerClass);
            resultsTable = cell(nRows+1,3);
            
            for i = 1 : nRows
                classStr = app.currentLabelingStrategy.classNames{i};
                detectedPerClass = nGoodEventsDetectedPerClass(i);
                if nTotalGoodEventsPerClass(i) == 0
                    detectionRate = 0;
                else
                    detectionRate = 100 * detectedPerClass / nTotalGoodEventsPerClass(i);
                end
                resultsTable{i,1} = classStr;
                resultsTable{i,2} = detectedPerClass;
                resultsTable{i,3} = sprintf('%.2f%%',detectionRate);
            end
            
            totalGoodEventsDetected = sum(nGoodEventsDetectedPerClass);
            totalGoodEvents = sum(nTotalGoodEventsPerClass);
            detectionRate = 100 * totalGoodEventsDetected / totalGoodEvents;
            
            resultsTable{nRows+1,1} = 'Total';
            resultsTable{nRows+1,2} = totalGoodEventsDetected;
            resultsTable{nRows+1,3} = detectionRate;

            app.classesResultTable.Data = cell2table(resultsTable);
        end
        
        
        %methods
        function events = loadEvents(app,selectedFileIdxs)
            eventDetector = app.eventDetectorConfigurator.createEventDetectorWithUIParameters();
            app.eventsLoader.eventDetector = eventDetector;
            events = app.eventsLoader.loadOrCreateEvents();
            events = events(selectedFileIdxs);
        end
        
        function computeDetectionResults(app,tolerance,selectedFileIdxs)
            if ~isempty(app.eventsPerFile) && ~isempty(app.annotationsPerFile)
                app.resultsComputer.tolerance = uint32(tolerance);
                annotations = app.annotationsPerFile(selectedFileIdxs);
                app.resultsPerFile = app.resultsComputer.computeDetectionResults(app.eventsPerFile,annotations);
            end
        end
        
        function fileIdx = getCurrentFileIdx(app)
            fileIdxStr = app.visualizationFilesList.Value;
            [~,fileIdx] = ismember(fileIdxStr,app.visualizationFilesList.Items);
        end
        
        function computeDetectionStatistics(app,selectedFileIdxs,selectedLabels)
            %nClasses = app.currentLabelingStrategy.numClasses-1;
            nClasses = length(selectedLabels);
            aggregatedClassResults = DetectionAggregatedResultsPerClass.computeAggregatedClassStatistics(app.resultsPerFile,nClasses);
            
            app.showResultTables();
            app.updateFilesTable(selectedFileIdxs);
            app.updateClassResultTable(aggregatedClassResults);
        end
        
        function saveEvents(app)
            if ~isempty(app.eventsPerFile)
                currentFileIdx = app.getCurrentFileIdx();
                events = app.eventsPerFile{currentFileIdx};
                app.dataLoader.saveEvents(events,Constants.kDetectedEventsFileName);
            end
        end
        
        function loadFilesList(app)
            app.filesList.Items = app.fileNames;
            app.filesList.Value = app.filesList.Items;
        end

    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            clear ClassesMap;
            app.dataLoader = DataLoader();
            app.eventsLoader = EventsLoader();
            app.resultsComputer = DetectionResultsComputer();
            app.preprocessedSignalsLoader = PreprocessedSignalsLoader();
            app.eventsLoader.preprocessedSignalsLoader = app.preprocessedSignalsLoader;
            app.eventsPlotter = DetectionEventsPlotter(app.plotAxes);

            %load data
            app.annotationsPerFile = app.dataLoader.loadAllAnnotations();
            
            dataFiles = Helper.listDataFiles();
            app.fileNames = Helper.removeDataFileExtensionForFiles(dataFiles);
            app.loadFilesList();
            
            %load ui configurators
            
            eventDetectors = {SimplePeakDetector, MatlabPeakDetector};
            app.eventDetectorConfigurator = EventDetectorConfigurator(...
                eventDetectors,...
                app.eventDetectorList...
                ,app.eventDetectorVariablesTable);
                 
            app.visualizationFilesList.Items = app.fileNames;
            if(length(app.fileNames) > 1)
                app.visualizationFilesList.Value = app.fileNames{1};
            end
            
            app.preprocessingConfigurator = PreprocessingConfigurator(...
                app.preprocessingSignalsList,...
                app.preprocessingSignalComputerList,...
                app.preprocessingSignalComputerVariablesTable);
            
            app.preprocessingConfigurator.setDefaultColumnNames();
            app.preprocessingSignalsList.Value = app.preprocessingSignalsList.Items{1};
            
            labelingStrategies = app.dataLoader.loadAllLabelingStrategies();
            
            app.labelingConfigurator = LabelingConfigurator(...
                labelingStrategies,...
                app.labelingStrategiesList,...
                app.groupingTable);
            
            app.currentLabelingStrategy = app.labelingConfigurator.getCurrentLabelingStrategy();
            
        end

        % Button pushed function: computeButton
        function computeButtonPushed(app, event)
            
            app.eventsPlotter.clearPlot();
            
            app.currentLabelingStrategy = app.labelingConfigurator.getCurrentLabelingStrategy();
            app.resultsComputer.labelingStrategy = app.currentLabelingStrategy;
            
            selectedFileIdxs = app.getSelectedFileIdxs();
            app.loadPreprocessedData(selectedFileIdxs);
            
            selectedLabels = app.labelingConfigurator.getSelectedLabels();
            app.eventsPerFile = app.loadEvents(selectedFileIdxs);
            
            tolerance = app.getTolerance();
            app.resultsComputer.positiveLabels = selectedLabels;
            app.computeDetectionResults(tolerance,selectedFileIdxs);
            app.computeDetectionStatistics(selectedFileIdxs,selectedLabels);
        end

        % Button pushed function: saveEventsButton
        function saveEventsButtonPushed(app, event)
            app.saveEvents();
        end

        % Button pushed function: visualizeButton
        function visualizeButtonPushed(app, event)
            
            if ~isempty(app.eventsPerFile)
                
                fileIdx = app.getCurrentFileIdx();
                currentFileResults = app.resultsPerFile(fileIdx);
                signal = app.fileEnergies{fileIdx};
                
                app.eventsPlotter.labelingStrategy = app.currentLabelingStrategy;
                app.eventsPlotter.plotResults(currentFileResults,signal);
            end
        end

        % Value changed function: showDetectedEventsCheckBox
        function showDetectedEventsCheckBoxValueChanged(app, event)
            value = app.showDetectedEventsCheckBox.Value;
            app.eventsPlotter.showingDetectedEvents = value;
        end

        % Value changed function: showMissedEventsCheckBox
        function showMissedEventsCheckBoxValueChanged(app, event)
            value = app.showMissedEventsCheckBox.Value;
            app.eventsPlotter.showingMissedEvents = value;
        end

        % Value changed function: showFalsePositiveEventsCheckBox
        function showFalsePositiveEventsCheckBoxValueChanged(app, event)
            value = app.showFalsePositiveEventsCheckBox.Value;
            app.eventsPlotter.showingFalsePositiveEvents = value;
        end

        % Selection changed function: plotToolbar
        function plotToolbarSelectionChanged(app, event)
            selectedButton = app.plotToolbar.SelectedObject;
            if(selectedButton == app.zoomButton)
                zoom(app.plotAxes,'on');
            else
                pan(app.plotAxes,'on');
            end
        end

        % Value changed function: selectAllFilesCheckBox
        function selectAllFilesCheckBoxValueChanged(app, event)
            value = app.selectAllFilesCheckBox.Value;
            if value == true
                app.filesList.Value = app.filesList.Items;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.plotAxes = uiaxes(app.UIFigure);
            title(app.plotAxes, '')
            xlabel(app.plotAxes, 'X')
            ylabel(app.plotAxes, 'Y')
            app.preprocessingPanel = uipanel(app.UIFigure);
            app.preprocessingPanel.Title = 'Preprocessing';
            app.preprocessingSignalComputerVariablesTable = uitable(app.preprocessingPanel);
            app.preprocessingSignalComputerVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.preprocessingSignalComputerVariablesTable.RowName = {};
            app.preprocessingSignalComputerVariablesTable.ColumnEditable = [false true];
            app.preprocessingSignalComputerList = uilistbox(app.preprocessingPanel);
            app.preprocessingSignalComputerList.Items = {};
            app.preprocessingSignalComputerList.Value = {};
            app.preprocessingSignalsList = uilistbox(app.preprocessingPanel);
            app.preprocessingSignalsList.Items = {};
            app.preprocessingSignalsList.Multiselect = 'on';
            app.preprocessingSignalsList.Value = {};
            app.eventDetectionPanel = uipanel(app.UIFigure);
            app.eventDetectionPanel.Title = 'Event Detection';
            app.eventDetectorVariablesTable = uitable(app.eventDetectionPanel);
            app.eventDetectorVariablesTable.ColumnName = {'Variable'; 'Value'};
            app.eventDetectorVariablesTable.RowName = {};
            app.eventDetectorVariablesTable.ColumnEditable = [false true];
            app.eventDetectorList = uilistbox(app.eventDetectionPanel);
            app.eventDetectorList.Items = {};
            app.eventDetectorList.Value = {};
            app.groupingPanel = uipanel(app.UIFigure);
            app.groupingPanel.Title = 'Grouping';
            app.labelingStrategiesList = uilistbox(app.groupingPanel);
            app.labelingStrategiesList.Items = {};
            app.labelingStrategiesList.Value = {};
            app.groupingTable = uitable(app.groupingPanel);
            app.groupingTable.ColumnName = {'Label'; ''};
            app.groupingTable.RowName = {};
            app.groupingTable.ColumnEditable = [false true];
            app.visualizationPanel = uipanel(app.UIFigure);
            app.visualizationPanel.Title = 'Visualization';
            app.visualizationFilesList = uilistbox(app.visualizationPanel);
            app.visualizationFilesList.Items = {};
            app.visualizationFilesList.Value = {};
            app.visualizeButton = uibutton(app.visualizationPanel, 'push');
            app.visualizeButton.ButtonPushedFcn = createCallbackFcn(app, @visualizeButtonPushed, true);
            app.visualizeButton.Text = 'Visualize';
            app.plotToolbar = uibuttongroup(app.UIFigure);
            app.plotToolbar.SelectionChangedFcn = createCallbackFcn(app, @plotToolbarSelectionChanged, true);
            app.zoomButton = uitogglebutton(app.plotToolbar);
            app.zoomButton.Text = 'Zoom';
            app.panButton = uitogglebutton(app.plotToolbar);
            app.panButton.Text = 'Pan';
            app.panButton.Value = true;
            app.exportPanel = uipanel(app.UIFigure);
            app.exportPanel.Title = 'Export';
            app.saveEventsButton = uibutton(app.exportPanel, 'push');
            app.saveEventsButton.ButtonPushedFcn = createCallbackFcn(app, @saveEventsButtonPushed, true);
            app.saveEventsButton.Text = 'Export';
            app.resultsPanel = uipanel(app.UIFigure);
            app.resultsPanel.Title = 'Results';
            app.ToleranceEditFieldLabel = uilabel(app.resultsPanel);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.toleranceTextBox = uieditfield(app.resultsPanel, 'numeric');
            app.toleranceTextBox.Value = 10;
            app.computeButton = uibutton(app.resultsPanel, 'push');
            app.computeButton.ButtonPushedFcn = createCallbackFcn(app, @computeButtonPushed, true);
            app.computeButton.Text = 'Compute';
            app.showDetectedEventsCheckBox = uicheckbox(app.UIFigure);
            app.showDetectedEventsCheckBox.ValueChangedFcn = createCallbackFcn(app, @showDetectedEventsCheckBoxValueChanged, true);
            app.showDetectedEventsCheckBox.Text = 'show detected';
            app.showDetectedEventsCheckBox.Value = true;
            app.showMissedEventsCheckBox = uicheckbox(app.UIFigure);
            app.showMissedEventsCheckBox.ValueChangedFcn = createCallbackFcn(app, @showMissedEventsCheckBoxValueChanged, true);
            app.showMissedEventsCheckBox.Text = 'show missed';
            app.showMissedEventsCheckBox.Value = true;
            app.showFalsePositiveEventsCheckBox = uicheckbox(app.UIFigure);
            app.showFalsePositiveEventsCheckBox.ValueChangedFcn = createCallbackFcn(app, @showFalsePositiveEventsCheckBoxValueChanged, true);
            app.showFalsePositiveEventsCheckBox.Text = 'show false positives';
            app.showFalsePositiveEventsCheckBox.Value = true;
            app.dataPanel = uipanel(app.UIFigure);
            app.dataPanel.Title = 'Data';
            app.filesList = uilistbox(app.dataPanel);
            app.filesList.Items = {};
            app.filesList.Multiselect = 'on';
            app.filesList.Value = {};
            app.selectAllFilesCheckBox = uicheckbox(app.dataPanel);
            app.selectAllFilesCheckBox.ValueChangedFcn = createCallbackFcn(app, @selectAllFilesCheckBoxValueChanged, true);
            app.selectAllFilesCheckBox.Text = 'select all';
            app.filesResultTable = uitable(app.UIFigure);
            app.filesResultTable.ColumnName = {'File'; 'Detected'; 'False Positive'};
            app.filesResultTable.RowName = {};
            app.classesResultTable = uitable(app.UIFigure);
            app.classesResultTable.ColumnName = {'File'; 'Detected'; 'Detection Rate'};
            app.classesResultTable.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = EventDetectionApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end