classdef OptimizationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        createButton                  matlab.ui.control.Button
        eventDetectionPanel           matlab.ui.container.Panel
        eventDetectionVariablesTable  matlab.ui.control.Table
        eventDetectionTable           matlab.ui.control.Table
        preprocessingPanel            matlab.ui.container.Panel
        preprocessingVariablesTable   matlab.ui.control.Table
        preprocessingTable            matlab.ui.control.Table
        signalsPanel                  matlab.ui.container.Panel
        signalsTable                  matlab.ui.control.Table
        metricsPanel                  matlab.ui.container.Panel
        AccuracySlider_2Label         matlab.ui.control.Label
        accuracySlider                matlab.ui.control.Slider
        FlopsSlider_2Label            matlab.ui.control.Label
        flopsSlider                   matlab.ui.control.Slider
        CommunicationSlider_2Label    matlab.ui.control.Label
        communicationSlider           matlab.ui.control.Slider
        constraintsPanel              matlab.ui.container.Panel
        constraintsTable              matlab.ui.control.Table
        segmentationPanel             matlab.ui.container.Panel
        segmentationVariablesTable    matlab.ui.control.Table
        segmentationTable             matlab.ui.control.Table
        featureExtractionPanel        matlab.ui.container.Panel
        featureExtractionVariablesTable  matlab.ui.control.Table
        featureExtractionTable        matlab.ui.control.Table
    end

    
    properties (Access = private)
        preprocessingComputers;
        eventDetectionComputers;
        segmentationComputers;
        featureComputers;
        classificationComputers;
    end
    
    methods (Access = private)
        
        function loadComputers(app)
            app.preprocessingComputers = Computer.PreprocessingComputers();
            app.eventDetectionComputers = Computer.EventDetectionComputers();
            app.segmentationComputers = Computer.SegmentationComputers();
            app.featureComputers = Computer.FeatureComputers();
            app.classificationComputers = Computer.ClassificationComputers();
        end
    
    
        function fillComputersTable(~,uiTable,computers)
            
            nComputers = length(computers);
            computerNames = Helper.generateComputerNamesArray(computers);
            uiTable.Data = table(computerNames',true(nComputers,1));
        end
    
        function updateVariablesTable(~,table,computer)
            properties = computer.getEditableProperties();
            
            numProperties = length(properties);
            cellArray = cell(numProperties,3);

            for i = 1 : numProperties
                property = properties(i);
                cellArray{i,1} = property.name;
                cellArray{i,2} = property.minValue;
                cellArray{i,3} = property.maxValue;
            end
            
            table.Data = cellArray;
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.loadComputers();
            
            app.fillComputersTable(app.preprocessingTable,app.preprocessingComputers);
            app.fillComputersTable(app.eventDetectionTable,app.eventDetectionComputers);
            app.fillComputersTable(app.segmentationTable,app.segmentationComputers);
            app.fillComputersTable(app.featureExtractionTable,app.featureComputers);
            
        end

        % Cell selection callback: preprocessingTable
        function preprocessingTableCellSelection(app, event)
            indices = event.Indices;
            computer = app.preprocessingComputers{indices(1)};
            app.updateVariablesTable(app.preprocessingVariablesTable,computer);
        end

        % Callback function: eventDetectionTable, 
        % segmentationVariablesTable
        function eventDetectionTableCellSelection(app, event)
            indices = event.Indices;
            computer = app.eventDetectionComputers{indices(1)};
            app.updateVariablesTable(app.eventDetectionVariablesTable,computer);
        end

        % Cell selection callback: segmentationVariablesTable
        function segmentationVariablesTableCellSelection(app, event)
            indices = event.Indices;
            
            computer = app.segmentationComputers{indices(1)};
            app.updateVariablesTable(app.segmentationVariablesTable,computer);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.createButton = uibutton(app.UIFigure, 'push');
            app.createButton.Text = 'Optimize';
            app.eventDetectionPanel = uipanel(app.UIFigure);
            app.eventDetectionPanel.Title = 'Event Detection';
            app.eventDetectionVariablesTable = uitable(app.eventDetectionPanel);
            app.eventDetectionVariablesTable.ColumnName = {'Variable'; 'Start'; 'Interval'; 'End'};
            app.eventDetectionVariablesTable.RowName = {};
            app.eventDetectionVariablesTable.ColumnEditable = [false true true];
            app.eventDetectionTable = uitable(app.eventDetectionPanel);
            app.eventDetectionTable.ColumnName = {'Computer'; ''};
            app.eventDetectionTable.RowName = {};
            app.eventDetectionTable.ColumnEditable = [false true];
            app.eventDetectionTable.CellSelectionCallback = createCallbackFcn(app, @eventDetectionTableCellSelection, true);
            app.preprocessingPanel = uipanel(app.UIFigure);
            app.preprocessingPanel.Title = 'Preprocessing';
            app.preprocessingVariablesTable = uitable(app.preprocessingPanel);
            app.preprocessingVariablesTable.ColumnName = {'Variable'; 'Start'; 'Interval'; 'End'};
            app.preprocessingVariablesTable.RowName = {};
            app.preprocessingVariablesTable.ColumnEditable = [false true true];
            app.preprocessingTable = uitable(app.preprocessingPanel);
            app.preprocessingTable.ColumnName = {'Computer'; ''};
            app.preprocessingTable.RowName = {};
            app.preprocessingTable.ColumnEditable = [false true];
            app.preprocessingTable.CellSelectionCallback = createCallbackFcn(app, @preprocessingTableCellSelection, true);
            app.signalsPanel = uipanel(app.UIFigure);
            app.signalsPanel.Title = 'Signals';
            app.signalsTable = uitable(app.signalsPanel);
            app.signalsTable.ColumnName = {'Signal'; ''};
            app.signalsTable.RowName = {};
            app.signalsTable.ColumnEditable = [false true];
            app.metricsPanel = uipanel(app.UIFigure);
            app.metricsPanel.Title = 'Metrics';
            app.AccuracySlider_2Label = uilabel(app.metricsPanel);
            app.AccuracySlider_2Label.Text = 'Accuracy';
            app.accuracySlider = uislider(app.metricsPanel);
            app.FlopsSlider_2Label = uilabel(app.metricsPanel);
            app.FlopsSlider_2Label.Text = 'Flops';
            app.flopsSlider = uislider(app.metricsPanel);
            app.CommunicationSlider_2Label = uilabel(app.metricsPanel);
            app.CommunicationSlider_2Label.Text = 'Communication';
            app.communicationSlider = uislider(app.metricsPanel);
            app.constraintsPanel = uipanel(app.UIFigure);
            app.constraintsPanel.Title = 'Constraints';
            app.constraintsTable = uitable(app.constraintsPanel);
            app.constraintsTable.ColumnName = {'Metric'; 'Min'; 'Max'};
            app.constraintsTable.RowName = {};
            app.constraintsTable.ColumnEditable = [false true true];
            app.segmentationPanel = uipanel(app.UIFigure);
            app.segmentationPanel.Title = 'Segmentation';
            app.segmentationVariablesTable = uitable(app.segmentationPanel);
            app.segmentationVariablesTable.ColumnName = {'Variable'; 'Start'; 'Interval'; 'End'};
            app.segmentationVariablesTable.RowName = {};
            app.segmentationVariablesTable.ColumnEditable = [false true true];
            app.segmentationVariablesTable.CellEditCallback = createCallbackFcn(app, @eventDetectionTableCellSelection, true);
            app.segmentationVariablesTable.CellSelectionCallback = createCallbackFcn(app, @segmentationVariablesTableCellSelection, true);
            app.segmentationTable = uitable(app.segmentationPanel);
            app.segmentationTable.ColumnName = {'Computer'; ''};
            app.segmentationTable.RowName = {};
            app.segmentationTable.ColumnEditable = [false true];
            app.featureExtractionPanel = uipanel(app.UIFigure);
            app.featureExtractionPanel.Title = 'Feature Extraction';
            app.featureExtractionVariablesTable = uitable(app.featureExtractionPanel);
            app.featureExtractionVariablesTable.ColumnName = {'Variable'; 'Start'; 'Interval'; 'End'};
            app.featureExtractionVariablesTable.RowName = {};
            app.featureExtractionVariablesTable.ColumnEditable = [false true true];
            app.featureExtractionTable = uitable(app.featureExtractionPanel);
            app.featureExtractionTable.ColumnName = {'Computer'; ''};
            app.featureExtractionTable.RowName = {};
            app.featureExtractionTable.ColumnEditable = [false true];
        end
    end

    methods (Access = public)

        % Construct app
        function app = OptimizationApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end